classdef Imageinfshow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FilenameEditFieldLabel  matlab.ui.control.Label
        FilenameEditField       matlab.ui.control.EditField
        FormatEditFieldLabel    matlab.ui.control.Label
        FormatEditField         matlab.ui.control.EditField
        WidthLabel              matlab.ui.control.Label
        WidthEditField          matlab.ui.control.EditField
        HeightEditFieldLabel    matlab.ui.control.Label
        HeightEditField         matlab.ui.control.EditField
        Button                  matlab.ui.control.Button
        NumelEditFieldLabel     matlab.ui.control.Label
        NumelEditField          matlab.ui.control.EditField
    end

    
    properties (Access = private)
      
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, image, numel)
         app.FilenameEditField.Value=image.Filename;
         app.FormatEditField.Value=image.Format;
         app.WidthEditField.Value=num2str(image.Width);
         app.HeightEditField.Value=num2str(image.Height);
         app.NumelEditField.Value=num2str(numel);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.Editable = 'off';
            app.FormatEditFieldLabel = uilabel(app.UIFigure);
            app.FormatEditFieldLabel.Text = 'Format';
            app.FormatEditField = uieditfield(app.UIFigure, 'text');
            app.FormatEditField.Editable = 'off';
            app.WidthLabel = uilabel(app.UIFigure);
            app.WidthLabel.Text = ' Width';
            app.WidthEditField = uieditfield(app.UIFigure, 'text');
            app.WidthEditField.Editable = 'off';
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'text');
            app.HeightEditField.Editable = 'off';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '关闭';
            app.NumelEditFieldLabel = uilabel(app.UIFigure);
            app.NumelEditFieldLabel.Text = 'Numel';
            app.NumelEditField = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Imageinfshow(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end