classdef Processing_Labotray < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProcessingLabotary  matlab.ui.Figure
        Channel2            matlab.ui.control.UIAxes
        ChannelGroup        matlab.ui.container.ButtonGroup
        Channel1Button      matlab.ui.control.RadioButton
        Channel2Button      matlab.ui.control.RadioButton
        Channel3Button      matlab.ui.control.RadioButton
        Panel               matlab.ui.container.Panel
        Label               matlab.ui.control.Label
        DropDown            matlab.ui.control.DropDown
        PictureGroup        matlab.ui.container.ButtonGroup
        Button_2            matlab.ui.control.RadioButton
        DCTButton           matlab.ui.control.RadioButton
        DFTButton           matlab.ui.control.RadioButton
        Button_7            matlab.ui.control.RadioButton
        Button_4            matlab.ui.control.Button
        Label_3             matlab.ui.control.Label
        DropDown_3          matlab.ui.control.DropDown
        Button              matlab.ui.control.Button
        Panel_2             matlab.ui.container.Panel
        Label_4             matlab.ui.control.Label
        Slider              matlab.ui.control.Slider
        Button_8            matlab.ui.control.Button
        Label_5             matlab.ui.control.Label
        Slider_2            matlab.ui.control.Slider
        HSICheckBox         matlab.ui.control.CheckBox
        Button_9            matlab.ui.control.Button
        SobelButton         matlab.ui.control.Button
        Button_10           matlab.ui.control.Button
        Button_11           matlab.ui.control.Button
        Button_14           matlab.ui.control.Button
        CheckBox            matlab.ui.control.CheckBox
        Button_13           matlab.ui.control.Button
        DropDown_4          matlab.ui.control.DropDown
        Label_2             matlab.ui.control.Label
        DropDown_2          matlab.ui.control.DropDown
        Button_3            matlab.ui.control.Button
        Button_5            matlab.ui.control.Button
        Button_6            matlab.ui.control.Button
        Channel1            matlab.ui.control.UIAxes
        Channel3            matlab.ui.control.UIAxes
        Channel1Label       matlab.ui.control.Label
        Channel2Label       matlab.ui.control.Label
        Channel3Label       matlab.ui.control.Label
        Panel_3             matlab.ui.container.Panel
        BWButton            matlab.ui.control.Button
        Label_6             matlab.ui.control.Label
        DropDown_6          matlab.ui.control.DropDown
        D0EditFieldLabel    matlab.ui.control.Label
        D0EditField         matlab.ui.control.NumericEditField
        GSButton            matlab.ui.control.Button
        D0EditField_2Label  matlab.ui.control.Label
        D0EditField_2       matlab.ui.control.NumericEditField
        Button_15           matlab.ui.control.Button
        D0EditField_3Label  matlab.ui.control.Label
        D0EditField_3       matlab.ui.control.NumericEditField
        Button_16           matlab.ui.control.Button
        D0EditField_4Label  matlab.ui.control.Label
        D0EditField_4       matlab.ui.control.NumericEditField
        D1EditFieldLabel    matlab.ui.control.Label
        D1EditField         matlab.ui.control.NumericEditField
        Switch              matlab.ui.control.ToggleSwitch
        Switch_2            matlab.ui.control.ToggleSwitch
        EnhencedHPPanel     matlab.ui.container.Panel
        Button_17           matlab.ui.control.Button
        K1EditFieldLabel    matlab.ui.control.Label
        K1EditField         matlab.ui.control.NumericEditField
        AECheckBox          matlab.ui.control.CheckBox
        D0EditField_5Label  matlab.ui.control.Label
        D0EditField_5       matlab.ui.control.NumericEditField
        K2EditFieldLabel    matlab.ui.control.Label
        K2EditField         matlab.ui.control.NumericEditField
        Lamp                matlab.ui.control.Lamp
        Lamp_2              matlab.ui.control.Lamp
        Lamp_3              matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        PresentChannel=1; % Description
        ReadImage=0; % Description
        ProcessingImage=0; % Description
        ProcessedImage=0;
        ImageInformation % Description
        InfDialog; % Description
        ImageWidth;
        ImageHeight;
        ImageNumel;
        trueImage=0; % Description
    end
    
    methods (Access = public)
        
        
    end
    
    methods (Access = private)
        %创建一个ChannelUsed函数，当用户选择一个通道使用了显示图片这样的操作的时候，自动更改channel选项，防止
        %笨比用户忘记
        %按照正常人的思维，一般是从第一个Channel开始显示图片，所以只需让channel指向下一个，如果已经到最后一个channel
        %则不需要执行该操作
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
       
            axis(app.Channel1,'off');
            axis(app.Channel2,'off');
            axis(app.Channel3,'off');
            %将复显按钮关闭；           
            app.Button_5.Enable='off';            
            %将图片信息按钮关闭          
            app.Button_6.Enable='off';
            %将处理对象选择关闭
            app.DropDown_3.Enable='off';
            %将清空按钮关闭
            app.Button_3.Enable='off';
            %将对比度调节按钮关闭
            app.Slider.Enable='off';
            %将均值滤波滑块关闭
            app.Slider_2.Enable='off';
            %将直方图均衡按钮关闭
            app.Button_8.Enable='off';
            %将hsicheckbox关闭
            app.HSICheckBox.Enable='off';
            %将转灰度图关闭
            app.Button_14.Enable='off';
            %将中值滤波和它的下拉框关闭
            app.Button_13.Enable='off';
            app.DropDown_4.Enable='off'
            %将拉式增强关闭
            app.Button_9.Enable='off';
            %将sobel关闭
            app.SobelButton.Enable='off';
            app.CheckBox.Enable='off';
            %将保存关闭
            app.Button_11.Enable='off';
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
          
            %打开图片
            [filename,pathname]=uigetfile({'*.jpg';'*.png';'*.tif';'*.jpeg'});
          if filename==0
              warndlg('请选择图片','警告');                  
              return
          end
          app.ReadImage=imread([pathname,filename]);
          app.ImageNumel=size(app.ReadImage,3);
          %如果是单色图，要禁用RGB通道,也要禁用空域处理中的彩色图选项,也要禁用hsicheckbox；
          %也要禁用转灰度图
          if app.ImageNumel==1
             app.DropDown.Value=app.DropDown.Items(1);
             app.DropDown.Enable='off';
             app.HSICheckBox.Enable='off';
             app.Button_14.Enable='off';
             app.DropDown_2.Value=app.DropDown_2.Items(1);
             app.Switch_2.Value=app.Switch_2.Items(2);
             app.Switch_2.Enable='off';
              
          elseif app.DropDown.Value>1
              app.DropDown.Enable='on';
              app.DropDown_2.Value=app.DropDown_2.Items(2);
              
              %将hsicheckbox打开
              app.HSICheckBox.Enable='on';
              %将转灰度图打开
              app.Button_14.Enable='on';
          end
          app.ImageInformation=imfinfo([pathname,filename]);         
          app.ImageHeight=app.ImageInformation.Height;
          app.ImageWidth=app.ImageInformation.Width;
          app.ProcessingImage=app.ReadImage;     
          %将复显按钮打开
          app.Button_5.Enable='on';
          %将图片信息按钮打开
          app.Button_6.Enable='on';
          %将清空按钮打开
          app.Button_3.Enable='on';
          %将对比度按钮打开
          app.Slider.Enable='on';
          %将均值滤波按钮打开
          app.Slider_2.Enable='on';
          %将直方图均衡打开
          app.Button_8.Enable='on';
          %将拉式增强打开
          app.Button_9.Enable='on';
          %将sobel增强打开
          app.SobelButton.Enable='on';
          app.CheckBox.Enable='on';
          %将中值滤波打开
          app.Button_13.Enable='on';
          app.DropDown_4.Enable='on';
          %将保存图片打开
          app.Button_11.Enable='on';
          %将频域操作中的一系列操作打开
          app.Switch.Enable='on';
          app.Switch_2.Enable='on';
          app.BWButton.Enable='on';
          app.GSButton.Enable='on';
          app.Button_15.Enable='on';
          app.Button_16.Enable='on';
          app.Button_17.Enable='on';
          app.DropDown_6.Enable='on';
          app.D0EditField.Enable='on';
          app.D0EditField_2.Enable='on';
          app.D0EditField_3.Enable='on';
          app.D0EditField_4.Enable='on';
          app.D0EditField_5.Enable='on';
          app.K1EditField.Enable='on';
          app.K2EditField.Enable='on';
          app.AECheckBox.Enable='on';
          app.D1EditField.Enable='on';
          %接下来显示图片
          switch app.PresentChannel
              case 1
                imshow(app.ReadImage,'parent',app.Channel1);
              case 2
                imshow(app.ReadImage,'parent',app.Channel2);  
              case 3
                imshow(app.ReadImage,'parent',app.Channel3);
          end                     
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
          
          im=imread('Image1.PNG');
          switch app.PresentChannel             
              case 1
                imshow(im,'parent',app.Channel1);
                reset(app.Channel1);
                axis(app.Channel1,'off');
              case 2
                imshow(im,'parent',app.Channel2);
                reset(app.Channel2);
                axis(app.Channel2,'off');
              case 3
                imshow(im,'parent',app.Channel3);
                reset(app.Channel3);
                axis(app.Channel3,'off');
          end
            
        end

        % Selection changed function: ChannelGroup
        function ChannelGroupSelectionChanged(app, event)
             if app.Channel1Button.Value==true
                presentChannel=1;
                app.Lamp.Color='green';
                app.Lamp_2.Color='red';
                 app.Lamp_3.Color='red';
            elseif app.Channel2Button.Value==true
                presentChannel=2;
                app.Lamp.Color='red';
                app.Lamp_2.Color='green';
                 app.Lamp_3.Color='red';
            elseif app.Channel3Button.Value==true
                presentChannel=3;
                app.Lamp.Color='red';
                app.Lamp_2.Color='red';
                 app.Lamp_3.Color='green';
            end
            app.PresentChannel=presentChannel;                    
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
         
            switch app.PresentChannel             
              case 1
                imshow(app.ReadImage,'parent',app.Channel1);
              case 2
                imshow(app.ReadImage,'parent',app.Channel2);
              case 3
                imshow(app.ReadImage,'parent',app.Channel3);
            end
             
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            %如果没有ReadImage，则退出
            if app.ReadImage==0
                warndlg('宁还没有读取图片呢','FBI WARNING')
                return
            end
            im=imread('Image1.PNG');
            %确定处理对象
            if strcmp(app.DropDown_3.Value,'ReadImage')
                NowImage=app.ReadImage;
            elseif strcmp(app.DropDown_3.Value,'Processed')
                NowImage=app.ProcessedImage;
            end
            %确定通道
            if strcmp(app.DropDown.Value,'灰度图')==1
                channel=1; %灰度图
            elseif strcmp(app.DropDown.Value,'R')==1
                channel=2; %R
            elseif strcmp(app.DropDown.Value,'G')==1
                channel=3; %G
            elseif strcmp(app.DropDown.Value,'B')==1
                channel=4; %B
            end
            %确定ProcessingImage
         
            switch channel
                case 1 %转灰度图
                    if size(NowImage,3)==1
                    app.ProcessingImage=NowImage;
                    else
                    app.ProcessingImage=rgb2gray(NowImage);
                    end
                    app.trueImage=app.ProcessingImage;                  
                case 2
                    app.ProcessingImage=NowImage(:,:,1);
                    app.trueImage=cat(3,app.ProcessingImage,zeros(size(app.ProcessingImage)),zeros(size(app.ProcessingImage)));
                case 3
                    app.ProcessingImage=NowImage(:,:,2);
                    app.trueImage=cat(3,zeros(size(app.ProcessingImage)),app.ProcessingImage,zeros(size(app.ProcessingImage)));
                case 4
                    app.ProcessingImage=NowImage(:,:,3);
                    app.trueImage=cat(3,zeros(size(app.ProcessingImage)),zeros(size(app.ProcessingImage)),app.ProcessingImage);
            end
            %确定图例方式
            if app.Button_2.Value==true
                Pictureway=1; %通道图
            elseif app.DCTButton.Value==true
                Pictureway=2; %DCT图
            elseif app.DFTButton.Value==true
                Pictureway=3; %DFT图
            elseif app.Button_7.Value==true
                Pictureway=4; %直方图         
            end
            %处理图像并显示
            switch app.PresentChannel
                case 1
                    switch Pictureway
                        case 1
                            imshow(app.ProcessingImage,'parent',app.Channel1);
                        case 2
                            imshow(dct2(im2double(app.ProcessingImage)),'parent',app.Channel1);
                        case 3
                            im=im2double(app.ProcessingImage);
                            F=ifftshift(fft2(im));
                            Im=log(1+abs(F));
                            imshow(Im,[],'parent',app.Channel1)
                        case 4
                            
                            [count,x]=imhist(app.ProcessingImage);
                            imshow(im,'parent',app.Channel1);
                            reset(app.Channel1);
                            axis(app.Channel1,'on');
                            stem(app.Channel1,x,count);
                    end
                 case 2
                    switch Pictureway
                        case 1
                            imshow(app.trueImage,'parent',app.Channel2);                     
                        case 2
                            imshow(dct2(im2double(app.ProcessingImage)),'parent',app.Channel2);
                        case 3
                            im=im2double(app.ProcessingImage);
                            F=ifftshift(fft2(im));
                            Im=log(1+abs(F));
                            imshow(Im,[],'parent',app.Channel2)
                        case 4
                            [count,x]=imhist(app.ProcessingImage);
                            imshow(im,'parent',app.Channel2);
                            reset(app.Channel2);
                            axis(app.Channel2,'on');
                            stem(app.Channel2,x,count);
                    end
                  case 3
                    switch Pictureway
                        case 1
                            imshow(app.trueImage,'parent',app.Channel3);
                        case 2
                            imshow(dct2(im2double(app.ProcessingImage)),'parent',app.Channel3);
                        case 3
                            im=im2double(app.ProcessingImage);
                            F=ifftshift(fft2(im));
                            Im=log(1+abs(F));
                            imshow(Im,[],'parent',app.Channel3)
                        case 4
                            [count,x]=imhist(app.ProcessingImage);
                            imshow(im,'parent',app.Channel3);
                            reset(app.Channel3);                          
                            axis(app.Channel3,'on');
                            stem(app.Channel3,x,count);
                    end
            end        
         
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.InfDialog=Imageinfshow(app.ImageInformation,app.ImageNumel);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            code=0;% code=1代表处理灰度图，code=2代表处理彩色图
            %把显示操作的处理对象打开
            app.DropDown_3.Enable='on';
            %首先确定处理对象
            if strcmp(app.DropDown_2.Value,'灰度图')
                app.ProcessedImage=app.ReadImage;
                code=1;
            elseif strcmp(app.DropDown_2.Value,'彩色图')
                app.ProcessedImage=app.ReadImage;  
                code=2;
            end
            %
            switch(code)
                case 1
                    %调用GrayEnhenced函数
                    app.ProcessedImage=GrayEnhencement(app.ProcessedImage,changingValue);
                case 2
                    %调用ColorAdjust函数
                    app.ProcessedImage=ColorAdjust(app.ProcessedImage,changingValue);
            end
            switch app.PresentChannel
                case 1
                    imshow(app.ProcessedImage,'Parent',app.Channel1);
                case 2
                    imshow(app.ProcessedImage,'Parent',app.Channel2);
                case 3
                    imshow(app.ProcessedImage,'Parent',app.Channel3);
            end


        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            code=0;% code=1代表处理灰度图，code=2代表处理彩色图
            %把显示操作的处理对象打开
            app.DropDown_3.Enable='on';
            %将保存图片打开
          app.Button_11.Enable='on';
            %首先确定处理对象
            if strcmp(app.DropDown_2.Value,'灰度图')
                app.ProcessedImage=app.ReadImage;
                code=1;
            elseif strcmp(app.DropDown_2.Value,'彩色图')
                app.ProcessedImage=app.ReadImage;  
                code=2;
            end
            switch code
                case 1
                    %直接使用histeq函数即可
                    app.ProcessedImage=histeq(app.ProcessedImage);
                case 2
                    %对彩色图像的I进行处理
                    hsi=RGB2HSI(app.ProcessedImage);
                    I=histeq(hsi(:,:,3));
                    newHsi=cat(3,hsi(:,:,1),hsi(:,:,2),I);
                    app.ProcessedImage=HSI2RGB(newHsi);                    
            end
            switch app.PresentChannel
                case 1
                    imshow(app.ProcessedImage,'Parent',app.Channel1);
                case 2
                    imshow(app.ProcessedImage,'Parent',app.Channel2);
                case 3
                    imshow(app.ProcessedImage,'Parent',app.Channel3);
            end
            
        end

        % Callback function
        function Button_9Pushed(app, event)
            app.ReadImage=rgb2gray(app.ReadImage);
            app.ImageNumel=1;
            switch app.PresentChannel
                case 1
                    imshow(app.ReadImage,'Parent',app.Channel1);
                case 2
                    imshow(app.ReadImage,'Parent',app.Channel2);
                case 2
                    imshow(app.ReadImage,'Parent',app.Channel3);
            end
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            changingValue = app.Slider_2.Value;
             %首先要建立一个从changingValue到滤波器尺寸的映射，且尺寸必须为整数
            %0~5=1
            %5~10=2
            %10~15=3
            %15~25=4
            %25~35=5
            %35~40=6
            %40~45=7
            %45~55=8
            %55~65=10
            %65~75=13
            %75~85=16
            %85~100=20；
            if changingValue<=5
                value=1;
                elseif changingValue<=10
                value=2;
                elseif changingValue<=15
                value=3;
                elseif changingValue<=25
                value=4;
                elseif changingValue<=35
                value=5;
                elseif changingValue<=40
                value=6;
                elseif changingValue<=45
                value=7;
                elseif changingValue<=55
                value=8;
                elseif changingValue<=65
                value=10;
                elseif changingValue<=75
                value=13;
                elseif changingValue<=85
                value=16;
                elseif changingValue<=100
                value=20;
            end    
            code=0;% code=1代表处理灰度图，code=2代表处理彩色图
            %把显示操作的处理对象打开
            app.DropDown_3.Enable='on';
            %首先确定处理对象
            if strcmp(app.DropDown_2.Value,'灰度图')
                app.ProcessedImage=app.ReadImage;
                code=1;
            elseif strcmp(app.DropDown_2.Value,'彩色图')
                app.ProcessedImage=app.ReadImage;  
                code=2;
            end
            %
            switch(code)
                case 1
                    %调用AverageFilter_gray函数
                    app.ProcessedImage=AverageFilter_gray(app.ProcessedImage,value);
                case 2
                    %调用ColorAdjust函数
                    if app.HSICheckBox.Value==1
                    app.ProcessedImage=AverageFilter_rgb(app.ProcessedImage,value,'hsi');
                    else
                    app.ProcessedImage=AverageFilter_rgb(app.ProcessedImage,value,'rgb');
                    end
            end
            switch app.PresentChannel
                case 1
                    imshow(app.ProcessedImage,'Parent',app.Channel1);
                case 2
                    imshow(app.ProcessedImage,'Parent',app.Channel2);
                case 3
                    imshow(app.ProcessedImage,'Parent',app.Channel3);
            end

        end

        % Button pushed function: Button_9
        function Button_9Pushed2(app, event)
            %由于这个函数对于灰度图和彩色图都可以使用，直接用就完事了
             %把显示操作的处理对象打开
            app.DropDown_3.Enable='on';
            %将保存图片打开
          app.Button_11.Enable='on';
            app.ProcessedImage=app.ReadImage;
            app.ProcessedImage=LaplaceEnhence(app.ProcessedImage);
            switch app.PresentChannel
                case 1
                    imshow(app.ProcessedImage,'Parent',app.Channel1);
                case 2
                    imshow(app.ProcessedImage,'Parent',app.Channel2);
                case 3
                    imshow(app.ProcessedImage,'Parent',app.Channel3);
            end
        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)
            %由于这个函数对于灰度图和彩色图都可以使用，直接用就完事了
             %把显示操作的处理对象打开
            app.DropDown_3.Enable='on';
            %将保存图片打开
          app.Button_11.Enable='on';
            app.ProcessedImage=app.ReadImage;
            if app.CheckBox.Value==1
                 app.ProcessedImage=SobelEnhence(app.ProcessedImage,'crossway');
            else
                 app.ProcessedImage=SobelEnhence(app.ProcessedImage,'lengthway');
            end
            switch app.PresentChannel
                case 1
                    imshow(app.ProcessedImage,'Parent',app.Channel1);
                case 2
                    imshow(app.ProcessedImage,'Parent',app.Channel2);
                case 3
                    imshow(app.ProcessedImage,'Parent',app.Channel3);
            end
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
             %由于这个函数对于灰度图和彩色图都可以使用，直接用就完事了
             %把显示操作的处理对象打开
            app.DropDown_3.Enable='on';
            %将保存图片打开
          app.Button_11.Enable='on';
            app.ProcessedImage=app.ReadImage;
            %确定滤波器大小
            if strcmp(app.DropDown_4.Value,'3')
                siz=3;
            elseif strcmp(app.DropDown_4.Value,'5')
                siz=5
            elseif  strcmp(app.DropDown_4.Value,'7')
                siz=7;
            elseif strcmp(app.DropDown_4.Value,'9')
                siz=9;
            end
            app.ProcessedImage=MidvalueFilter(app.ProcessedImage,siz);
            switch app.PresentChannel
                case 1
                    imshow(app.ProcessedImage,'Parent',app.Channel1);
                case 2
                    imshow(app.ProcessedImage,'Parent',app.Channel2);
                case 3
                    imshow(app.ProcessedImage,'Parent',app.Channel3);
            end
            
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            app.DropDown_3.Enable='on';
            %将保存图片打开
          app.Button_11.Enable='on';
            app.ProcessedImage=app.ReadImage;
            app.ProcessedImage=rgb2gray(app.ProcessedImage);
            switch app.PresentChannel
                case 1
                    imshow(app.ProcessedImage,'Parent',app.Channel1);
                case 2
                    imshow(app.ProcessedImage,'Parent',app.Channel2);
                case 3
                    imshow(app.ProcessedImage,'Parent',app.Channel3);
            end
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            [filename,pathname]=uiputfile({'*.jpg';'*.jpeg';'*.tif'},'choose a file');
            if filename==0
                warndlg('请选择保存地址','warning');
                return
            else
               imwrite(app.ProcessedImage,[pathname,filename]);
            end           
        end

        % Button pushed function: BWButton
        function BWButtonPushed(app, event)
          app.DropDown_3.Enable='on';
          %将保存图片打开
          app.Button_11.Enable='on';
          app.ProcessedImage=app.ReadImage;
          %确定处理方式（高通，低通）,way=1代表低通，way=2代表高通
          if strcmp(app.Switch.Value,'低通')
              way=1;
          else
              way=2;
          end
          %确定处理域 (RGB,HSI),domin=1代表RGB域，domin=2代表HSI域
          if strcmp(app.Switch_2.Value,'RGB域')
              domin=1;
          else
              domin=2;
          end
          switch way
              case 1
                  switch domin
                      case 1
                         app.ProcessedImage= LPFOnlyforRGB(app.ProcessedImage,'butterworth',app.D0EditField.Value,str2double(app.DropDown_6.Value),1);
                      case 2
                        app.ProcessedImage= LPF(app.ProcessedImage,'butterworth',app.D0EditField.Value,str2double(app.DropDown_6.Value));
                  end
              case 2
                  switch domin
                      case 1
                         app.ProcessedImage= HPFOnlyforRGB(app.ProcessedImage,'butterworth',app.D0EditField.Value,str2double(app.DropDown_6.Value));               
                      case 2
                         app.ProcessedImage= HPF(app.ProcessedImage,'butterworth',app.D0EditField.Value,str2double(app.DropDown_6.Value));     
                  end
          end
          switch app.PresentChannel
                case 1
                    imshow(app.ProcessedImage,'Parent',app.Channel1);
                case 2
                    imshow(app.ProcessedImage,'Parent',app.Channel2);
                case 3
                    imshow(app.ProcessedImage,'Parent',app.Channel3);       
          end
        end

        % Button pushed function: GSButton
        function GSButtonPushed(app, event)
           app.DropDown_3.Enable='on';
          %将保存图片打开
          app.Button_11.Enable='on';
          app.ProcessedImage=app.ReadImage;
           %确定处理方式（高通，低通）,way=1代表低通，way=2代表高通
          if strcmp(app.Switch.Value,'低通')
              way=1;
          else
              way=2;
          end
          %确定处理域 (RGB,HSI),domin=1代表RGB域，domin=2代表HSI域
          if strcmp(app.Switch_2.Value,'RGB域')
              domin=1;
          else
              domin=2;
          end
         switch way
              case 1
                  switch domin
                      case 1
                         app.ProcessedImage= LPFOnlyforRGB(app.ProcessedImage,'gaussian',app.D0EditField_2.Value);
                      case 2
                        app.ProcessedImage= LPF(app.ProcessedImage,'gaussian',app.D0EditField_2.Value);
                  end
              case 2
                  switch domin
                      case 1
                         app.ProcessedImage= HPFOnlyforRGB(app.ProcessedImage,'gaussian',app.D0EditField_2.Value);               
                      case 2
                         app.ProcessedImage= HPF(app.ProcessedImage,'gaussian',app.D0EditField_2.Value);     
                  end
         end
            switch app.PresentChannel
                case 1
                    imshow(app.ProcessedImage,'Parent',app.Channel1);
                case 2
                    imshow(app.ProcessedImage,'Parent',app.Channel2);
                case 3
                    imshow(app.ProcessedImage,'Parent',app.Channel3);       
            end  
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
          app.DropDown_3.Enable='on';
          %将保存图片打开
          app.Button_11.Enable='on';
         app.ProcessedImage=app.ReadImage;
           %确定处理方式（高通，低通）,way=1代表低通，way=2代表高通
          if strcmp(app.Switch.Value,'低通')
              way=1;
          else
              way=2;
          end
          %确定处理域 (RGB,HSI),domin=1代表RGB域，domin=2代表HSI域
          if strcmp(app.Switch_2.Value,'RGB域')
              domin=1;
          else
              domin=2;
          end
          switch way
              case 1
                  switch domin
                      case 1
                         app.ProcessedImage= LPFOnlyforRGB(app.ProcessedImage,'ideal',app.D0EditField_3.Value);
                      case 2
                        app.ProcessedImage= LPF(app.ProcessedImage,'ideal',app.D0EditField_3.Value);
                  end
              case 2
                  switch domin
                      case 1
                         app.ProcessedImage= HPFOnlyforRGB(app.ProcessedImage,'ideal',app.D0EditField_3.Value);               
                      case 2
                         app.ProcessedImage= HPF(app.ProcessedImage,'ideal',app.D0EditField_3.Value);     
                  end
          end
           switch app.PresentChannel
                case 1
                    imshow(app.ProcessedImage,'Parent',app.Channel1);
                case 2
                    imshow(app.ProcessedImage,'Parent',app.Channel2);
                case 3
                    imshow(app.ProcessedImage,'Parent',app.Channel3);       
            end  
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
          app.DropDown_3.Enable='on';
          %将保存图片打开
          app.Button_11.Enable='on';
          app.ProcessedImage=app.ReadImage;
           %确定处理方式（高通，低通）,way=1代表低通，way=2代表高通
          if strcmp(app.Switch.Value,'低通')
              way=1;
          else
              way=2;
          end
          %确定处理域 (RGB,HSI),domin=1代表RGB域，domin=2代表HSI域
          if strcmp(app.Switch_2.Value,'RGB域')
              domin=1;
          else
              domin=2;
          end
          switch way
              case 1
                  switch domin
                      case 1
                         app.ProcessedImage= LPFOnlyforRGB(app.ProcessedImage,'trapezoid',app.D0EditField_4.Value,1,app.D1EditField.Value);
                      case 2
                        app.ProcessedImage= LPF(app.ProcessedImage,'trapezoid',app.D0EditField_4.Value,1,app.D1EditField.Value);
                  end
              case 2
                  switch domin
                      case 1
                         app.ProcessedImage= HPFOnlyforRGB(app.ProcessedImage,'trapezoid',app.D0EditField_4.Value,1,app.D1EditField.Value);               
                      case 2
                         app.ProcessedImage= HPF(app.ProcessedImage,'trapezoid',app.D0EditField_4.Value,1,app.D1EditField.Value);     
                  end
          end
           switch app.PresentChannel
                case 1
                    imshow(app.ProcessedImage,'Parent',app.Channel1);
                case 2
                    imshow(app.ProcessedImage,'Parent',app.Channel2);
                case 3
                    imshow(app.ProcessedImage,'Parent',app.Channel3);       
            end        
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
             app.DropDown_3.Enable='on';
          %将保存图片打开
          app.Button_11.Enable='on';
          app.ProcessedImage=app.ReadImage;
          D0=app.D0EditField_5.Value;
          K1=app.K1EditField.Value;
          K2=app.K2EditField.Value;
          if app.AECheckBox.Value==true
              choice=1;
          else
              choice=0;
          end
          app.ProcessedImage=EnhencedHPF(app.ProcessedImage,D0,K1,K2,choice);
          switch app.PresentChannel
                case 1
                    imshow(app.ProcessedImage,'Parent',app.Channel1);
                case 2
                    imshow(app.ProcessedImage,'Parent',app.Channel2);
                case 3
                    imshow(app.ProcessedImage,'Parent',app.Channel3);       
          end     
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProcessingLabotary = uifigure;
            app.ProcessingLabotary.Name = 'UI Figure';
            app.Channel2 = uiaxes(app.ProcessingLabotary);
            title(app.Channel2, {''; ''; ''})
            xlabel(app.Channel2, 'X')
            ylabel(app.Channel2, 'Y')
            app.ChannelGroup = uibuttongroup(app.ProcessingLabotary);
            app.ChannelGroup.SelectionChangedFcn = createCallbackFcn(app, @ChannelGroupSelectionChanged, true);
            app.ChannelGroup.Title = '选择显示通道';
            app.Channel1Button = uiradiobutton(app.ChannelGroup);
            app.Channel1Button.Text = 'Channel1';
            app.Channel1Button.Value = true;
            app.Channel2Button = uiradiobutton(app.ChannelGroup);
            app.Channel2Button.Text = 'Channel2';
            app.Channel3Button = uiradiobutton(app.ChannelGroup);
            app.Channel3Button.Text = 'Channel3';
            app.Panel = uipanel(app.ProcessingLabotary);
            app.Panel.Title = '显示操作';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '选择通道';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'灰度图', 'R', 'G', 'B'};
            app.DropDown.Value = '灰度图';
            app.PictureGroup = uibuttongroup(app.Panel);
            app.PictureGroup.Title = '选择图例样式';
            app.Button_2 = uiradiobutton(app.PictureGroup);
            app.Button_2.Text = '通道图';
            app.Button_2.Value = true;
            app.DCTButton = uiradiobutton(app.PictureGroup);
            app.DCTButton.Text = 'DCT图';
            app.DFTButton = uiradiobutton(app.PictureGroup);
            app.DFTButton.Text = 'DFT图';
            app.Button_7 = uiradiobutton(app.PictureGroup);
            app.Button_7.Text = '直方图';
            app.Button_4 = uibutton(app.Panel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '显示';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '处理对象';
            app.DropDown_3 = uidropdown(app.Panel);
            app.DropDown_3.Items = {'ReadImage', 'Processed'};
            app.DropDown_3.Value = 'ReadImage';
            app.Button = uibutton(app.ProcessingLabotary, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '读取图片';
            app.Panel_2 = uipanel(app.ProcessingLabotary);
            app.Panel_2.Title = '空域处理操作';
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '对比度调整';
            app.Slider = uislider(app.Panel_2);
            app.Slider.MajorTickLabels = {'低', '', '', '', '', '高', '', ''};
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 50;
            app.Button_8 = uibutton(app.Panel_2, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '直方图均衡';
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '均值滤波';
            app.Slider_2 = uislider(app.Panel_2);
            app.Slider_2.MajorTickLabels = {'0', '2', '4', '6', '8', '10', '', '', '', ''};
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.HSICheckBox = uicheckbox(app.Panel_2);
            app.HSICheckBox.Text = 'HSI域';
            app.Button_9 = uibutton(app.Panel_2, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed2, true);
            app.Button_9.Text = '拉式细节增强';
            app.SobelButton = uibutton(app.Panel_2, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel边缘增强';
            app.Button_10 = uibutton(app.Panel_2, 'push');
            app.Button_10.Text = '自定义滤波';
            app.Button_11 = uibutton(app.Panel_2, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '保存';
            app.Button_14 = uibutton(app.Panel_2, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '转灰度图';
            app.CheckBox = uicheckbox(app.Panel_2);
            app.CheckBox.Text = '横向/纵向';
            app.Button_13 = uibutton(app.Panel_2, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '中值滤波';
            app.DropDown_4 = uidropdown(app.Panel_2);
            app.DropDown_4.Items = {'3', '5', '7', '9'};
            app.DropDown_4.Value = '3';
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '处理对象';
            app.DropDown_2 = uidropdown(app.Panel_2);
            app.DropDown_2.Items = {'灰度图', '彩色图'};
            app.DropDown_2.Enable = 'off';
            app.DropDown_2.Value = '灰度图';
            app.Button_3 = uibutton(app.ProcessingLabotary, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '清空';
            app.Button_5 = uibutton(app.ProcessingLabotary, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '图片复显';
            app.Button_6 = uibutton(app.ProcessingLabotary, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '图片信息';
            app.Channel1 = uiaxes(app.ProcessingLabotary);
            title(app.Channel1, {''; ''; ''; ''})
            xlabel(app.Channel1, 'X')
            ylabel(app.Channel1, 'Y')
            app.Channel3 = uiaxes(app.ProcessingLabotary);
            title(app.Channel3, {''; ''; ''})
            xlabel(app.Channel3, 'X')
            ylabel(app.Channel3, 'Y')
            app.Channel1Label = uilabel(app.ProcessingLabotary);
            app.Channel1Label.Text = 'Channel1';
            app.Channel2Label = uilabel(app.ProcessingLabotary);
            app.Channel2Label.Text = {'Channel2'; ''};
            app.Channel3Label = uilabel(app.ProcessingLabotary);
            app.Channel3Label.Text = {'Channel3'; ''};
            app.Panel_3 = uipanel(app.ProcessingLabotary);
            app.Panel_3.Title = '频域处理操作';
            app.BWButton = uibutton(app.Panel_3, 'push');
            app.BWButton.ButtonPushedFcn = createCallbackFcn(app, @BWButtonPushed, true);
            app.BWButton.Enable = 'off';
            app.BWButton.Text = 'BW滤波';
            app.Label_6 = uilabel(app.Panel_3);
            app.Label_6.Enable = 'off';
            app.Label_6.Text = '阶数';
            app.DropDown_6 = uidropdown(app.Panel_3);
            app.DropDown_6.Items = {'1', '2', '3', '4', '5', '10', '15'};
            app.DropDown_6.Enable = 'off';
            app.DropDown_6.Value = '1';
            app.D0EditFieldLabel = uilabel(app.Panel_3);
            app.D0EditFieldLabel.Enable = 'off';
            app.D0EditFieldLabel.Text = 'D0';
            app.D0EditField = uieditfield(app.Panel_3, 'numeric');
            app.D0EditField.Enable = 'off';
            app.D0EditField.Value = 50;
            app.GSButton = uibutton(app.Panel_3, 'push');
            app.GSButton.ButtonPushedFcn = createCallbackFcn(app, @GSButtonPushed, true);
            app.GSButton.Enable = 'off';
            app.GSButton.Text = 'GS滤波';
            app.D0EditField_2Label = uilabel(app.Panel_3);
            app.D0EditField_2Label.Enable = 'off';
            app.D0EditField_2Label.Text = 'D0';
            app.D0EditField_2 = uieditfield(app.Panel_3, 'numeric');
            app.D0EditField_2.Enable = 'off';
            app.D0EditField_2.Value = 50;
            app.Button_15 = uibutton(app.Panel_3, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Enable = 'off';
            app.Button_15.Text = '理想滤波';
            app.D0EditField_3Label = uilabel(app.Panel_3);
            app.D0EditField_3Label.Enable = 'off';
            app.D0EditField_3Label.Text = 'D0';
            app.D0EditField_3 = uieditfield(app.Panel_3, 'numeric');
            app.D0EditField_3.Enable = 'off';
            app.D0EditField_3.Value = 50;
            app.Button_16 = uibutton(app.Panel_3, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Enable = 'off';
            app.Button_16.Text = '梯形滤波';
            app.D0EditField_4Label = uilabel(app.Panel_3);
            app.D0EditField_4Label.Enable = 'off';
            app.D0EditField_4Label.Text = 'D0';
            app.D0EditField_4 = uieditfield(app.Panel_3, 'numeric');
            app.D0EditField_4.Enable = 'off';
            app.D0EditField_4.Value = 40;
            app.D1EditFieldLabel = uilabel(app.Panel_3);
            app.D1EditFieldLabel.Enable = 'off';
            app.D1EditFieldLabel.Text = 'D1';
            app.D1EditField = uieditfield(app.Panel_3, 'numeric');
            app.D1EditField.Enable = 'off';
            app.D1EditField.Value = 80;
            app.Switch = uiswitch(app.Panel_3, 'toggle');
            app.Switch.Items = {'低通', '高通'};
            app.Switch.Enable = 'off';
            app.Switch.Value = '低通';
            app.Switch_2 = uiswitch(app.Panel_3, 'toggle');
            app.Switch_2.Items = {'RGB域', 'HSI域/灰度'};
            app.Switch_2.Enable = 'off';
            app.Switch_2.Value = 'RGB域';
            app.EnhencedHPPanel = uipanel(app.Panel_3);
            app.EnhencedHPPanel.Title = 'EnhencedHP';
            app.Button_17 = uibutton(app.EnhencedHPPanel, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Enable = 'off';
            app.Button_17.Text = '增强高斯高通滤波';
            app.K1EditFieldLabel = uilabel(app.EnhencedHPPanel);
            app.K1EditFieldLabel.Enable = 'off';
            app.K1EditFieldLabel.Text = 'K1';
            app.K1EditField = uieditfield(app.EnhencedHPPanel, 'numeric');
            app.K1EditField.Enable = 'off';
            app.K1EditField.Value = 0.5;
            app.AECheckBox = uicheckbox(app.EnhencedHPPanel);
            app.AECheckBox.Enable = 'off';
            app.AECheckBox.Text = 'AE';
            app.D0EditField_5Label = uilabel(app.EnhencedHPPanel);
            app.D0EditField_5Label.Enable = 'off';
            app.D0EditField_5Label.Text = 'D0';
            app.D0EditField_5 = uieditfield(app.EnhencedHPPanel, 'numeric');
            app.D0EditField_5.Enable = 'off';
            app.D0EditField_5.Value = 30;
            app.K2EditFieldLabel = uilabel(app.EnhencedHPPanel);
            app.K2EditFieldLabel.Enable = 'off';
            app.K2EditFieldLabel.Text = 'K2';
            app.K2EditField = uieditfield(app.EnhencedHPPanel, 'numeric');
            app.K2EditField.Enable = 'off';
            app.K2EditField.Value = 0.75;
            app.Lamp = uilamp(app.ProcessingLabotary);
            app.Lamp_2 = uilamp(app.ProcessingLabotary);
            app.Lamp_3 = uilamp(app.ProcessingLabotary);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Processing_Labotray

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProcessingLabotary)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProcessingLabotary)
        end
    end
end